classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NewtonsCalculatorLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        Lamp                    matlab.ui.control.Lamp
        ForceNEditField         matlab.ui.control.NumericEditField
        ForceNEditFieldLabel    matlab.ui.control.Label
        Accms2EditField         matlab.ui.control.NumericEditField
        Accms2EditFieldLabel    matlab.ui.control.Label
        MassKgEditField         matlab.ui.control.NumericEditField
        MassKgEditFieldLabel    matlab.ui.control.Label
        CalculateButton         matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            mass=app.MassKgEditField.Value;
            acc=app.Accms2EditField.Value;
            
            %Newton's Second Law F = ma
            force = mass*acc;

            app.ForceNEditField.Value = force;

            if force >= 0 && force <=500 
                app.Lamp.Color = 'g';
            elseif force > 500 && force <= 1000
                app.Lamp.Color = [0.98,0.69,0.02];
            else 
                app.Lamp.Color = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.CalculateButton = uibutton(app.Panel, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.MassKgEditFieldLabel = uilabel(app.Panel);
            app.MassKgEditFieldLabel.Text = 'Mass (Kg)';
            app.MassKgEditField = uieditfield(app.Panel, 'numeric');
            app.Accms2EditFieldLabel = uilabel(app.Panel);
            app.Accms2EditFieldLabel.Text = 'Acc (m/s^2)';
            app.Accms2EditField = uieditfield(app.Panel, 'numeric');
            app.ForceNEditFieldLabel = uilabel(app.Panel);
            app.ForceNEditFieldLabel.Text = 'Force (N)';
            app.ForceNEditField = uieditfield(app.Panel, 'numeric');
            app.Lamp = uilamp(app.Panel);
            app.NewtonsCalculatorLabel = uilabel(app.UIFigure);
            app.NewtonsCalculatorLabel.Text = 'Newton''s Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end