classdef generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneratorUIFigure     matlab.ui.Figure
        title                 matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        l_gen_EditField       matlab.ui.control.NumericEditField
        QLabel_4              matlab.ui.control.Label
        l_unit_gen_EditField  matlab.ui.control.EditField
        i_gen_EditField       matlab.ui.control.NumericEditField
        QLabel_3              matlab.ui.control.Label
        i_unit_gen_EditField  matlab.ui.control.EditField
        v_gen_EditField       matlab.ui.control.NumericEditField
        QLabel_2              matlab.ui.control.Label
        v_unit_gen_EditField  matlab.ui.control.EditField
        q_gen_EditField       matlab.ui.control.NumericEditField
        QLabel                matlab.ui.control.Label
        q_unit_gen_EditField  matlab.ui.control.EditField
        p_gen_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel       matlab.ui.control.Label
        p_unit_gen_EditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        callingapp % Description
        generator_id ;
        generator_state;
        p_gen;
        v_gen;
        l_gen;
        i_gen;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, guisimulation, generator_id, generator_state, p, v, l, i)
            app.callingapp = guisimulation;
            app.generator_id = num2str(generator_id);
            app.title.Text = strcat(app.title.Text,app.generator_id);
            app.p_gen = p;
            app.v_gen = v;
            app.l_gen = l;
            app.i_gen = i;
            app.p_gen_EditField.Value = p;
            app.v_gen_EditField.Value = v;
            app.i_gen_EditField.Value = i;
            app.l_gen_EditField.Value = l;
        end

        % Key press function: GeneratorUIFigure
        function GeneratorUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'escape')
                delete(app);
            end
        end

        % Value changed function: p_gen_EditField
        function p_gen_EditFieldValueChanged(app, event)
            value = app.p_gen_EditField.Value;
            app.p_gen = value;
            importdatagen_1(app.callingapp,app.p_gen,app.v_gen,app.l_gen,app.i_gen);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneratorUIFigure = uifigure('Visible', 'off');
            app.GeneratorUIFigure.Name = 'Generator';
            app.GeneratorUIFigure.KeyPressFcn = createCallbackFcn(app, @GeneratorUIFigureKeyPress, true);
            app.ButtonGroup = uibuttongroup(app.GeneratorUIFigure);
            app.p_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.p_unit_gen_EditField.Value = 'MW';
            app.PEditFieldLabel = uilabel(app.ButtonGroup);
            app.PEditFieldLabel.Text = 'P:';
            app.p_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.p_gen_EditField.ValueChangedFcn = createCallbackFcn(app, @p_gen_EditFieldValueChanged, true);
            app.q_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.q_unit_gen_EditField.Value = 'MVar';
            app.QLabel = uilabel(app.ButtonGroup);
            app.QLabel.Text = 'Q:';
            app.q_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.v_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.v_unit_gen_EditField.Value = 'kV';
            app.QLabel_2 = uilabel(app.ButtonGroup);
            app.QLabel_2.Text = 'V:';
            app.v_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.i_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.i_unit_gen_EditField.Value = 'A';
            app.QLabel_3 = uilabel(app.ButtonGroup);
            app.QLabel_3.Text = 'I:';
            app.i_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.l_unit_gen_EditField = uieditfield(app.ButtonGroup, 'text');
            app.l_unit_gen_EditField.Value = '%';
            app.QLabel_4 = uilabel(app.ButtonGroup);
            app.QLabel_4.Text = 'L:';
            app.l_gen_EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.title = uilabel(app.GeneratorUIFigure);
            app.title.Text = 'GENERATOR UNIT # ';
            % Show the figure after all components are created
            app.GeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneratorUIFigure)
        end
    end
end